classdef DetectorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        SelectcharactertotrackLabel  matlab.ui.control.Label
        ButtonGroup                  matlab.ui.container.ButtonGroup
        UppercharacterButton         matlab.ui.control.RadioButton
        LowerCharacterButton         matlab.ui.control.RadioButton
        Switch                       matlab.ui.control.ToggleSwitch
        UIAxesMainPanel              matlab.ui.control.UIAxes
        UIAxesUpperPanel             matlab.ui.control.UIAxes
        UIAxesLowerPanel             matlab.ui.control.UIAxes
        TextArea                     matlab.ui.control.TextArea
        DetectedfacesLabel           matlab.ui.control.Label
        TrainNetworkButton           matlab.ui.control.Button
        StartVideoButton             matlab.ui.control.Button
        SelectdirectorywithtrainingSetButton  matlab.ui.control.Button
        SelectVideoFileButton        matlab.ui.control.Button
    end


    properties (Access = private)
        Property % Description
%          upperPhotoPath = 'E:\TBBT Training Set Data\shel3.png';
%          lowerPhotoPath = 'E:\TBBT Training Set Data\how.png';
          videoExample = 'E:\TBBT Training Set Data\video_test18.mp4';
%           videoExample = 'E:\TBBT Training Set Data\double_face2.png';
         detectedPointsUpperPhoto;
         detectedPointsLowerPhoto;
         detectedPointsVideo;
         flagSelectedPhotoNumber = 1;
         facesD;
         bbox;
         bboxPointsArray;
         videoFileReader;
         videoFrame;
         numberOfDetectedFaces;
         detectionThreshold = 10;
         faces;
         isAtLeastOneFaceDetected;
         network;
         iteratorBorder;
         dirpath;
         detectedFacesLabels = char.empty;
         
    end

    methods (Access = private)
    
        function results = detectFromVideoStart(app)
            
            faceDetector = vision.CascadeObjectDetector();  
            faceDetector.MergeThreshold = app.detectionThreshold;
            app.videoFileReader = vision.VideoFileReader(app.videoExample);       
            app.videoFrame = step(app.videoFileReader);
            app.bbox = step(faceDetector, app.videoFrame);
                       
            while true
                
                while (isempty(app.bbox) == 1)                    
                    app.videoFrame = step(app.videoFileReader);
                    app.bbox = step(faceDetector, app.videoFrame);
                end
                                     
                app.videoFrame = insertShape(app.videoFrame, 'Rectangle', app.bbox);           
                imshow(app.videoFrame, 'Parent', app.UIAxesMainPanel);           
                      
                sizeHolder = size(app.bbox);
                app.numberOfDetectedFaces = sizeHolder(1);
                     
                convertBboxToPoints(app);
               
                detectFeaturePointsInFaceRegion(app);
          
                checkIfAtLeastOneFaceIsDetected(app);
                
                if app.isAtLeastOneFaceDetected
                    break;
                end
              
           end
                                                  
           showBoxesWithDetectedFacesInSidePanel(app);      
                                                                      
        end
        
        
        function results = trackSelectedDace(app)
                                     
                pointTracker = vision.PointTracker('MaxBidirectionalError', 2);
        
                face = app.faces{app.flagSelectedPhotoNumber};
                facePointsArray = face.Location;             
                initialize(pointTracker, facePointsArray, app.videoFrame);
         
                videoPlayer  = vision.VideoPlayer('Position',...
                          [100 100 [size(app.videoFrame, 2), size(app.videoFrame, 1)]+30]);
        
                
                oldPoints = facePointsArray;
                
                
                while ~isDone(app.videoFileReader)
            
                     app.videoFrame = step(app.videoFileReader);
                     [AllPointsForFaces, isFound] = step(pointTracker, app.videoFrame);   
                     visiblePoints = AllPointsForFaces(isFound, :);  
                     oldInliers = oldPoints(isFound, :);
             
                     if size(visiblePoints, 1) >= 2 % need at least 2 points
                 
                 
                     [xform, oldInliers, visiblePoints] = estimateGeometricTransform(...
                         oldInliers, visiblePoints, 'similarity', 'MaxDistance', 4);
                                              
                         app.bboxPointsArray{app.flagSelectedPhotoNumber} = transformPointsForward(xform, app.bboxPointsArray{app.flagSelectedPhotoNumber});                            
                        
                         bboxPolygon = reshape(app.bboxPointsArray{app.flagSelectedPhotoNumber}', 1, []);
                         app.videoFrame = insertShape(app.videoFrame, 'Polygon', bboxPolygon, 'LineWidth', 2);
                         app.videoFrame = insertText(app.videoFrame, [bboxPolygon(1) bboxPolygon(2)-30], char(app.detectedFacesLabels{app.flagSelectedPhotoNumber}));
                             
                       
                         app.videoFrame = insertMarker(app.videoFrame, visiblePoints, '+', 'Color', 'white');       
                     
                         % Reset the points
                         oldPoints = visiblePoints;
                         setPoints(pointTracker, oldPoints);   
%                          detectAdditionalFacesDuringVideo(app);                                               
                    end
                                                          
                 step(videoPlayer, app.videoFrame);
%                  detectAdditionalFacesDuringVideo(app);                 
                 
                 end
                                   
                release(app.videoFileReader);
                release(videoPlayer);
                release(pointTracker);
        end       
        
        function results = showBoxesWithDetectedFacesInSidePanel(app)
            
%              arrayOfPanels = [ app.UIAxesUpperPanel app.UIAxesLowerPanel];
                       
%              iteratorBorder = 0;
%              if size(arrayOfPanels, 2) < app.numberOfDetectedFaces       
%                     iteratorBorder = size(arrayOfPanels, 2);                                                                    
%                 else
%                     iteratorBorder = app.numberOfDetectedFaces;
%              end                       
            
%              for i = 1:iteratorBorder                                                 
%                  newImage = imcrop(app.videoFrame, app.bbox(i,:));
%                  imshow( newImage, 'Parent', arrayOfPanels(i));
%              end   
            
        end
    
        
        function results = convertBboxToPoints(app)
            app.bboxPointsArray = {};
            for i=1:app.numberOfDetectedFaces
               app.bboxPointsArray{i} = bbox2points(app.bbox(i, :));
            end
        end
    
        
        function results = detectFeaturePointsInFaceRegion(app)
             app.faces = {};
             for i = 1:app.numberOfDetectedFaces
                 face = detectMinEigenFeatures(rgb2gray(app.videoFrame), 'ROI', app.bbox(i,:));               
                 if eq(face.Count, 0) == 1                                                 %  go back to face detector method here in some kind of loop
                     app.videoFrame = step(app.videoFileReader);
                     app.bbox = step(faceDetector, app.videoFrame);
                 else
                     app.faces{i} = face;                    
                 end
             end 
        end
    
        
        function results = checkIfAtLeastOneFaceIsDetected(app)
            app.isAtLeastOneFaceDetected = (isempty(app.bbox) == 0 && isempty(app.faces) == 0);           
        end
        
        function results = detectAdditionalFacesDuringVideo(app) 
             faceDetector = vision.CascadeObjectDetector();
             faceDetector.MergeThreshold = app.detectionThreshold;
             app.bbox = step(faceDetector, app.videoFrame);
             disp("new bbox with faces:");
             disp(app.bbox);
             if isempty(app.bbox) == 0
                newImage2 = imcrop(app.videoFrame, app.bbox(1,:));
                imshow( newImage2, 'Parent',  app.UIAxesLowerPanel);
             end
             
             
        end
        
        function results = trainNeuralNetwork(app)
            digitDatasetPath = fullfile('E:\TBBT Training Set Data\Set\','Ml_Data_1000\data');
            imds = imageDatastore(digitDatasetPath, ...
                'IncludeSubfolders',true,'LabelSource','foldernames');


            imds.ReadFcn = @customreader2;
            
%             labelCount = countEachLabel(imds);
            
            img = readimage(imds,1);
            size(img);
            reset(imds);
            
            numTrainFiles = 750;
            [imdsTrain,imdsValidation] = splitEachLabel(imds,numTrainFiles,'randomize');
            
            
            layers = [
                imageInputLayer([150 150 3])
                           
                convolution2dLayer(3,8,'Padding',1)
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(2,'Stride',2)
                
                convolution2dLayer(3,16,'Padding',1)
                batchNormalizationLayer
                reluLayer
                
                maxPooling2dLayer(2,'Stride',2)
                
                convolution2dLayer(3,32,'Padding',1)
                batchNormalizationLayer
                reluLayer
                
                fullyConnectedLayer(3)
                softmaxLayer
                classificationLayer];
            
            
            options = trainingOptions('sgdm', ...
                'MaxEpochs',13, ...
                'ValidationData',imdsValidation, ...
                'ValidationFrequency',30, ...
                'Verbose',false, ...
                'Plots','training-progress');


            net = trainNetwork(imdsTrain,layers,options);
            app.network = net;
            
            
            YPred = classify(net,imdsValidation);
            YValidation = imdsValidation.Labels;
            
            accuracy = sum(YPred == YValidation)/numel(YValidation);
            
            function data = customreader2(filename)
                onState = warning('off', 'backtrace');
                c = onCleanup(@() warning(onState));
                data = imread(filename);
                data = data(:,:,min(1:3, end)); 
                data = imresize(data, [150 150]);
            end
        end
        
        function results = classifyDetectedFaces(app)
            
            arrayOfPanels = [ app.UIAxesUpperPanel app.UIAxesLowerPanel];
            calculateIteratorBorderWithPanelForFaces(app);
            
             for i = 1:app.iteratorBorder                                                 
%                  newImage = imcrop(app.videoFrame, app.bbox(i,:));                
                 im = imcrop(app.videoFrame, app.bbox(i,:));
                 image(arrayOfPanels(i), im)
                 im = imresize(im,[150 150]);
                 [label,score] = classify(app.network,im);
                 title(arrayOfPanels(i), {char(label),num2str(max(score),2)});
                 disp('label:');
                 disp(label);
                 app.detectedFacesLabels{i} = char(label);
                 bboxPolygon = reshape(app.bboxPointsArray{i}', 1, []);
                 app.videoFrame = insertShape(app.videoFrame, 'Polygon', bboxPolygon, 'LineWidth', 2);
                 app.videoFrame = insertText(app.videoFrame, [bboxPolygon(1) bboxPolygon(2)-30], char(label));
                 imshow(app.videoFrame, 'Parent', app.UIAxesMainPanel);
              end   
            
        end
        
        function results = calculateIteratorBorderWithPanelForFaces(app)
             arrayOfPanels = [ app.UIAxesUpperPanel app.UIAxesLowerPanel];
                       
             app.iteratorBorder = 0;
             if size(arrayOfPanels, 2) < app.numberOfDetectedFaces       
                    app.iteratorBorder = size(arrayOfPanels, 2);                                                                    
                else
                    app.iteratorBorder = app.numberOfDetectedFaces;
             end                       
        end
        
        function results = clearImagesOnSidePanels(app)
             cla( app.UIAxesUpperPanel, 'reset');
             cla( app.UIAxesLowerPanel, 'reset');
             arrayOfPanels = [ app.UIAxesUpperPanel app.UIAxesLowerPanel];
              for i = 1:size(arrayOfPanels)                                                                   
                  cla(arrayOfPanels(i), 'reset');
              end             
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%               trainNeuralNetwork(app);
%               detectFromVideoStart(app);        
%               classifyDetectedFaces(app);
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;            
            if selectedButton == app.UppercharacterButton       
                app.flagSelectedPhotoNumber = 1;                             
            elseif selectedButton == app.LowerCharacterButton                        
                app.flagSelectedPhotoNumber = 2;                               
            else
                disp("none");
            end
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            app.TextArea.Value = value;
            if 1 == strcmp(value, 'START TRACKING')
                trackSelectedDace(app);                                
            elseif 1 == strcmp(value, 'RESET')              
%                  closereq;  
                   clearImagesOnSidePanels(app);
                   detectFromVideoStart(app);        
                   classifyDetectedFaces(app);
            end
        end

        % Button pushed function: TrainNetworkButton
        function StartTraining(app, event)
            trainNeuralNetwork(app);
        end

        % Button pushed function: StartVideoButton
        function StartVideo(app, event)
             detectFromVideoStart(app);        
             classifyDetectedFaces(app);
        end

        % Button pushed function: SelectdirectorywithtrainingSetButton
        function SelectDir(app, event)
            [file,app.dirpath] = uigetfile('E:\TBBT Training Set Data\Set\Ml_Data\', ...
                            'Select Trainng Set');
        end

        % Button pushed function: SelectVideoFileButton
        function SelectVideoFile(app, event)
            [file, path] = uigetfile('E:\TBBT Training Set Data\');
            app.videoExample = [path, file];
            app.TextArea.Value = app.videoExample;
            disp(app.videoExample);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SelectcharactertotrackLabel = uilabel(app.UIFigure);
            app.SelectcharactertotrackLabel.Text = 'Select character to track';
            app.ButtonGroup = uibuttongroup(app.UIFigure);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.UppercharacterButton = uiradiobutton(app.ButtonGroup);
            app.UppercharacterButton.Text = 'Upper character';
            app.UppercharacterButton.Value = true;
            app.LowerCharacterButton = uiradiobutton(app.ButtonGroup);
            app.LowerCharacterButton.Text = 'Lower Character';
            app.Switch = uiswitch(app.UIFigure, 'toggle');
            app.Switch.Items = {'RESET', 'START TRACKING'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'RESET';
            app.UIAxesMainPanel = uiaxes(app.UIFigure);
            app.UIAxesUpperPanel = uiaxes(app.UIFigure);
            app.UIAxesLowerPanel = uiaxes(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.DetectedfacesLabel = uilabel(app.UIFigure);
            app.DetectedfacesLabel.Text = 'Detected faces';
            app.TrainNetworkButton = uibutton(app.UIFigure, 'push');
            app.TrainNetworkButton.ButtonPushedFcn = createCallbackFcn(app, @StartTraining, true);
            app.TrainNetworkButton.Text = 'Train Network';
            app.StartVideoButton = uibutton(app.UIFigure, 'push');
            app.StartVideoButton.ButtonPushedFcn = createCallbackFcn(app, @StartVideo, true);
            app.StartVideoButton.Text = 'Start Video';
            app.SelectdirectorywithtrainingSetButton = uibutton(app.UIFigure, 'push');
            app.SelectdirectorywithtrainingSetButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDir, true);
            app.SelectdirectorywithtrainingSetButton.Text = 'Select directory with training Set';
            app.SelectVideoFileButton = uibutton(app.UIFigure, 'push');
            app.SelectVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectVideoFile, true);
            app.SelectVideoFileButton.Text = 'Select Video File';
        end
    end

    methods (Access = public)

        % Construct app
        function app = DetectorApp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end